classdef LCOS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        unintcostkwhLabel           matlab.ui.control.Label
        uc_new                      matlab.ui.control.NumericEditField
        uc_degraded                 matlab.ui.control.NumericEditField
        uc_pb                       matlab.ui.control.NumericEditField
        cycLabel                    matlab.ui.control.Label
        cyc                         matlab.ui.control.NumericEditField
        life_degraded               matlab.ui.control.NumericEditField
        life_pb                     matlab.ui.control.NumericEditField
        lifetimeaEditFieldLabel     matlab.ui.control.Label
        life_new                    matlab.ui.control.NumericEditField
        tthEditFieldLabel           matlab.ui.control.Label
        tt                          matlab.ui.control.NumericEditField
        CrdkwhLabel                 matlab.ui.control.Label
        c                           matlab.ui.control.NumericEditField
        LCOS_degraded               matlab.ui.control.NumericEditField
        LCOS_pb                     matlab.ui.control.NumericEditField
        LCOSLabel                   matlab.ui.control.Label
        LCOS_new                    matlab.ui.control.NumericEditField
        runButton                   matlab.ui.control.Button
        exchangerateEditFieldLabel  matlab.ui.control.Label
        exchangerateEditField       matlab.ui.control.NumericEditField
        NewLiBsButton               matlab.ui.control.Button
        DegradedLiBsButton          matlab.ui.control.Button
        PbacidbatteriesButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        er; % Description
        
    end
    

    methods (Access = private)

        % Button pushed function: runButton
        function runButtonPushed(app, event)
        %% 考虑Win
%这个和之前最大的不同是加入了买电成本，所以不同之处在买电那块
%这里有两个变量cyc和Pout
%这里考虑DOD为定值，第三个考虑DOD为变化值，得到最好的DOD
%当电价不同的时候也会产生很大影响
%% Different
%1.加入了买电成本
%2.WO变成二维数组
%% 初期投资 万元/Mwh
app.er=app.exchangerateEditField.Value;
PCS=75.4;%万元/Mwh  10元/kwh
cost_construct=90;%建筑费用  万元/Mwh
cost_equip=11;%相关设备费用
cost_A=app.uc_new.Value/10*app.er;
cost_BB=app.uc_degraded.Value/10*app.er;
cost_C=app.uc_pb.Value/10*app.er;
L_A=3000;
L_B=500;
L_C=300;
cost_B_re=28;%退役电池重组费用
cost_B_tr=22.56;%退役电池运输费用
cost_B=cost_B_re+cost_B_tr+cost_BB;
cost_buy_battery=[cost_A cost_B cost_C];
cost_total_A=cost_construct+cost_equip+cost_A;
cost_total_B=cost_construct+cost_equip+cost_B+cost_B_re+cost_B_tr;
cost_total_C=cost_construct+cost_C;
cost_init=[cost_total_A cost_total_B cost_total_C];
%建立一个初期投资数组，分别是芯电池，旧电池，铅酸电池

%% 这里计算N年的贴现比例
%建立两个数组
%i和I分别表示三种贴现率和三种N年贴现
N=25;%计划年限
Tr=0.25;%所得税税率
i=0.1;%贴现率


I=(1/(1+i))*(1-(1/(1+i))^N)/(1-(1/(1+i)));%


%% 这个里面不考虑Win,这里是放电量  MWh/年 
cyc=app.cyc.Value;
%cyc是每年循环的次数
%这个图形不考虑Win，而且最后推到出的LCOE式子和Pout*tout无关，所以这里可以随意
%设置Pout*tout=400MWh
Pout=10;%单位 放电MW/一次循环
DOD=0.8;%放电深度
nin=[0.953 0.85 0.70];%
nout=[0.953 0.85 0.7];%
ninv=0.97;
tt=app.tt.Value;
tout=tt*DOD;%实际放电h/一次循环

FLH=cyc*tt;%单位  放电h/年
Wout=Pout*FLH;
Wt=Pout*tt;%每年的总放电量

Uout=220;%这个随便给，只是在求解储能电站容量的时候需要用，其他地方会抵消掉
Cnet=Pout*tout.*nout./Uout;%这个是净容量  Ah
Cr=Cnet./DOD;%额定容量 Ah
rsd=[0.01 0.03 0.03];%自放电率 每个月的分别是新电池，旧电池，铅酸电池
Win=12.*Cr.*Uout.*rsd+Wout./(nin.*nout.*ninv.*ninv);
Cthp=app.c.Value/10;%谷时电价 单位  万元/MWh  10元/kwh
Cwin=Cthp.*Win;%这里不考虑贴现，因为直接把这个买电项目放在一边


%% 运维费用
Opr=[0.8 1.5 1.1];%新电池，退役电池，铅酸电池 万元/MWh


%% 折旧抵税,这里因为要考虑资产更新，所以先考虑把未来的更换电池直接换到固定总资产里面去
%利用总固定资产*（1-残值率）/折旧年限=折旧额
%这里使用平均年限法计算折旧额*税率  万元/MWh
%这里修改了下，因为电池更新多算了一次，需要用th(t)-1

R=[0.05 0.05 0.05];%依次是新的电池，退役电池，铅酸电池的残值率
Lf=[app.life_new.Value app.life_degraded.Value app.life_pb.Value];%依次是新的电池，退役电池，铅酸电池的寿命
th=[];%这个算的是需要更换多少次新电
for t=1:1:3
    o=ceil(N/Lf(t));
    th=[th o];
end

tol_ch=[];%总固定资产,把未来的更换电池直接换到固定总资产里  万元/MWh
for t=1:1:3
    o=cost_init(t);
    tol_ch=[tol_ch o];
end
ze=[];%折旧额抵税收每年的
for t=1:1:3
    o=tol_ch(t)*(1-R(t))/N*Tr;
    ze=[ze o];

end

%% 这个算的是运行过程中的再投资，其中的更新电池

%再投资，再次购买电池，分别算出不同贴现率下的再投资 25年的总的开销
%单位 万元/MWh
I2=[];%这个数组是关于更换电池的贴现比值
%不同电池购买次数是不一样的，所以贴现也不一样，所以需要分别计算
%这里同样，每列代表的是相同的贴现率
for m=1:1:3
        p=(1/(1+i)^(th(m)-1))*(1-(1/(1+i)^(th(m)-1))^(th(m)-1))/(1-(1/(1+i)^(th(m)-1)));
         I2=[I2,p];
end


RE=I2.*cost_buy_battery;

%% 购买的电池残值计算  总共是25年的购买电池得到的残值 万元/MWh
Chan=tol_ch.*R./(1+i)^N;


%% 开始
A=[cost_init(1).*Wt Opr(1).*I.*Pout RE(1).*Wt ze(1).*I.*Wt Chan(1).*Wt Cwin(1).*I];
B=[cost_init(2).*Wt Opr(2).*I.*Pout RE(2).*Wt ze(2).*I.*Wt Chan(2).*Wt Cwin(2).*I];
C=[cost_init(3).*Wt Opr(3).*I.*Pout RE(3).*Wt ze(3).*I.*Wt Chan(3).*Wt Cwin(3).*I];
app.LCOS_new.Value=(A(1)+A(3)-A(4)+A(2)-A(5)+A(6)+PCS*Pout*I)*10./(app.er*I*Wout);
app.LCOS_degraded.Value=(B(1)+B(3)-B(4)+B(2)-B(5)+B(6)+PCS*Pout*I).*10./(app.er*I*Wout);
app.LCOS_pb.Value=(C(1)+C(3)-C(4)+C(2)-C(5)+C(6)+PCS*Pout*I).*10./(app.er*I*Wout);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.unintcostkwhLabel = uilabel(app.Panel);
            app.unintcostkwhLabel.Text = 'unint cost[￥/kwh]';
            app.uc_new = uieditfield(app.Panel, 'numeric');
            app.uc_new.Value = 283;
            app.uc_degraded = uieditfield(app.Panel, 'numeric');
            app.uc_degraded.Value = 96.31;
            app.uc_pb = uieditfield(app.Panel, 'numeric');
            app.uc_pb.Value = 145;
            app.cycLabel = uilabel(app.Panel);
            app.cycLabel.Text = 'cyc[]';
            app.cyc = uieditfield(app.Panel, 'numeric');
            app.cyc.Value = 365;
            app.life_degraded = uieditfield(app.Panel, 'numeric');
            app.life_degraded.Value = 4;
            app.life_pb = uieditfield(app.Panel, 'numeric');
            app.life_pb.Value = 2;
            app.lifetimeaEditFieldLabel = uilabel(app.Panel);
            app.lifetimeaEditFieldLabel.Text = 'life time[a]';
            app.life_new = uieditfield(app.Panel, 'numeric');
            app.life_new.Value = 10;
            app.tthEditFieldLabel = uilabel(app.Panel);
            app.tthEditFieldLabel.Text = 'tt[h]';
            app.tt = uieditfield(app.Panel, 'numeric');
            app.tt.Value = 6;
            app.CrdkwhLabel = uilabel(app.Panel);
            app.CrdkwhLabel.Text = 'Crd[￥/kwh]';
            app.c = uieditfield(app.Panel, 'numeric');
            app.c.Value = 0.3089;
            app.LCOS_degraded = uieditfield(app.Panel, 'numeric');
            app.LCOS_pb = uieditfield(app.Panel, 'numeric');
            app.LCOSLabel = uilabel(app.Panel);
            app.LCOSLabel.Text = 'LCOS[$]';
            app.LCOS_new = uieditfield(app.Panel, 'numeric');
            app.runButton = uibutton(app.Panel, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'run';
            app.exchangerateEditFieldLabel = uilabel(app.Panel);
            app.exchangerateEditFieldLabel.Text = 'exchange rate';
            app.exchangerateEditField = uieditfield(app.Panel, 'numeric');
            app.exchangerateEditField.Value = 6.91;
            app.NewLiBsButton = uibutton(app.Panel, 'push');
            app.NewLiBsButton.Enable = 'off';
            app.NewLiBsButton.Text = 'New  LiBs';
            app.DegradedLiBsButton = uibutton(app.Panel, 'push');
            app.DegradedLiBsButton.Enable = 'off';
            app.DegradedLiBsButton.Text = 'Degraded LiBs';
            app.PbacidbatteriesButton = uibutton(app.Panel, 'push');
            app.PbacidbatteriesButton.Enable = 'off';
            app.PbacidbatteriesButton.Text = 'Pb-acid batteries';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LCOS

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end